classdef pong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pong                          matlab.ui.Figure
        MenuPanel                     matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        NickPlayer1EditField          matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NickPlayer2EditField          matlab.ui.control.EditField
        HighscoreTOP10Label           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SkillButtonGroup              matlab.ui.container.ButtonGroup
        leichtButton                  matlab.ui.control.RadioButton
        mittelButton                  matlab.ui.control.RadioButton
        schwerButton                  matlab.ui.control.RadioButton
        KeyboardControlLabel          matlab.ui.control.Label
        Player1ArrowUpArrowDownLabel  matlab.ui.control.Label
        Player2WSLabel                matlab.ui.control.Label
        PONGLabel                     matlab.ui.control.Label
        GameModeButtonGroup           matlab.ui.container.ButtonGroup
        onePlayer                     matlab.ui.control.RadioButton
        twoPlayer                     matlab.ui.control.RadioButton
        RoundsSpinnerLabel            matlab.ui.control.Label
        RoundsSpinner                 matlab.ui.control.Spinner
        PausePLabel                   matlab.ui.control.Label
        ExitQLabel                    matlab.ui.control.Label
        Version10Label                matlab.ui.control.Label
    end

    
    properties (Access = public)
        DatabaseName=('pongHighscoreDatabase.xlsx')     %Initialisierit den Dateinamen der Excel Datei in der die Highsocre Daten liegen.
        gamemode=1;                                     %Initialisiert den Spielermodus als 1 Player
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%   Bildschirm Auflösung ermitteln
            userScreenSize = get(0,'ScreenSize');                                                                                   %Ermittelt die Auflösung vom Bildschirm  Quelle: https://ch.mathworks.com/matlabcentral/fileexchange/31177-dave-s-matlab-pong
            %   screenSize ist ein Vektor mit 4 Elementen: [links, unten, breite, höhe]
            app.Pong.Position= [(userScreenSize(3)-app.Pong.Position(3))/2, (userScreenSize(4)-app.Pong.Position(4))/2, app.Pong.Position(3),app.Pong.Position(4)]; % Berechnet die Window Startpositon vom Menu.
            
            try
                app.UITable.Data= pongHighscoreRead(app.DatabaseName);                                                              %Liest die Highscore Daten beim aufstarten der App ein und stellt diese auf dem UiTabel dar.
            catch exception                                                                                                         %Falls die Highscore Datei nicht vorhanden ist, wird die Exception hier abgegfangen.
                disp(exception);                                                                                                    %Text-Ausgabe im MATLAB Command Window.
                A={'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0; 'Pong!',0};   %Erstellen der Initialisierungsmatrix.
                xlswrite(app.DatabaseName,A);                                                                                       %Excel Datei erstellen und mit Initialisierungsmatrix befühlen.
                disp('Status: Highscore database file has now been created.')                                                       %Text-Ausgabe im MATLAB Command Window.
                app.UITable.Data= pongHighscoreRead(app.DatabaseName);                                                              %Liest die Highsocre Daten ein und stellt diese auf dem UiTabel dar.
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            %%Überprüfen von Spielstart Konditionen.
            name1false = strcmp(app.NickPlayer1EditField.Value,'Nickname too long');    %Überprüft ob der Nickname im Feld Player 1 als zulange erkannt wurde.
            name2false = strcmp(app.NickPlayer2EditField.Value,'Nickname too long');    %Überprüft ob der Nickname im Feld Player 2 als zulange erkannt wurde.
            gamemode1=strcmp(app.GameModeButtonGroup.SelectedObject.Text,'1 Player');   %Überprüft ob der Spielmodus 1 Player eingestellt wurde -> true
            gamemode2= strcmp(app.GameModeButtonGroup.SelectedObject.Text, '2 Players');%Überprüft ob der Spielmodus 2 Players eingestellt wurde -> true
            if gamemode2                                                                %Falls der Spielmodus 2 Player gewählt wurde.
                if ~(name1false|name2false)                                             %Überprüfen ob KEIN Nickname zu lange ist.
                    app.Pong.Visible='off'                                              %Das Menu Fenster wird ausgeblendet.
                    pongengine(app);                                                    %Das Objekt app wird dem Spiel übergeben und dieses gestartet.
                end
            elseif gamemode1                                                            %Falls der Spielmodus 1 Player gewählt wurde.
                if ~(name1false)                                                        %Überprüft ob der Nickname vom Spieler 1 nicht zu lange ist.
                    app.Pong.Visible='off'                                              %Das Menu Fenster wird ausgeblendet.
                    pongengine(app);                                                    %Das Objekt app wird dem Spiel übergeben und dieses gestartet.
                end
            end
        end

        % Selection changed function: GameModeButtonGroup
        function GameModeButtonGroupSelectionChanged(app, event)
            
            %%Menu Anzeige an Spieleranzahlt anpassen.
            selectedButton = app.GameModeButtonGroup.SelectedObject.Text;               %Übergibt den Wert vom Gamemodus Auswahlbereich der Variable. (String)
            switch selectedButton                                                       %Switch Case für Gamemodus
                case '1 Player'                                                         %1 Spieler Modus
                    app.NickPlayer2EditField.Visible='Off';                             %Blendet das EditField für die Eingabe vom Nicknamen für den Player 2 aus.
                    app.SkillButtonGroup.Visible='On';                                  %Blendet die Eingabe für die Skillklasse vom Computer ein.
                    
                case '2 Players'                                                        %2 Spieler Modus
                    app.NickPlayer2EditField.Visible='On';                              %Blendet das EditField für die Eingabe vom Nicknamen für den Player 2 ein.
                    app.SkillButtonGroup.Visible='Off';                                 %Blendet die Eingabe für die Skillklasse vom Computer aus.
                    
            end
        end

        % Value changed function: NickPlayer1EditField
        function NickPlayer1EditFieldValueChanged(app, event)
            %%Überprüfen ob Nickname ein gültiger Wert ist.
            value = app.NickPlayer1EditField.Value;

            if length(value)>32                                                         %Gibt die Anzahl zeichen zurück
                app.NickPlayer1EditField.Value='Nickname too long'                      %Ist der Nickname von Player 1 länger als der definiert Schwellwert wird in der Nicknamen Eingabe Maske "Nickname too long" angezeigt.
                app.NickPlayer1EditField.FontColor= [1 0 0];                            %Text Farbe auf rot setzen
            else
                app.NickPlayer1EditField.FontColor= [0 0 0];                            %Text Farbe auf schwarz setzen
            end
        end

        % Value changed function: NickPlayer2EditField
        function NickPlayer2EditFieldValueChanged(app, event)
            %%Überprüfen ob Nickname ein gültiger Wert ist.
            value = app.NickPlayer2EditField.Value;
            
            if length(value)>32                                                         %Gibt die Anzahl zeichen zurück
                app.NickPlayer2EditField.Value='Nickname too long'                      %Ist der Nickname von Player 2 länger als der definiert Schwellwert wird in der Nicknamen Eingabe Maske "Nickname too long" angezeigt.
                app.NickPlayer2EditField.FontColor= [1 0 0];                            %Text Farbe auf rot setzen
            else
                app.NickPlayer2EditField.FontColor= [0 0 0];                            %Text Farbe auf schwarz setzen
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pong = uifigure;
            app.Pong.Name = 'Pong';
            app.MenuPanel = uipanel(app.Pong);
            app.MenuPanel.Title = 'Menu';
            app.PlayButton = uibutton(app.MenuPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play!';
            app.Label_2 = uilabel(app.MenuPanel);
            app.Label_2.Text = '';
            app.NickPlayer1EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer1EditField.ValueChangedFcn = createCallbackFcn(app, @NickPlayer1EditFieldValueChanged, true);
            app.NickPlayer1EditField.Value = 'Player 1 Name';
            app.Label = uilabel(app.MenuPanel);
            app.Label.Text = '';
            app.NickPlayer2EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer2EditField.ValueChangedFcn = createCallbackFcn(app, @NickPlayer2EditFieldValueChanged, true);
            app.NickPlayer2EditField.Visible = 'off';
            app.NickPlayer2EditField.Value = 'Player 2 Name';
            app.HighscoreTOP10Label = uilabel(app.MenuPanel);
            app.HighscoreTOP10Label.Text = 'Highscore TOP 10';
            app.UITable = uitable(app.MenuPanel);
            app.UITable.ColumnName = {'Rank'; 'Player'; 'Score'};
            app.UITable.RowName = {};
            app.SkillButtonGroup = uibuttongroup(app.MenuPanel);
            app.SkillButtonGroup.Title = 'Skill';
            app.leichtButton = uiradiobutton(app.SkillButtonGroup);
            app.leichtButton.Text = 'leicht';
            app.leichtButton.Value = true;
            app.mittelButton = uiradiobutton(app.SkillButtonGroup);
            app.mittelButton.Text = 'mittel';
            app.schwerButton = uiradiobutton(app.SkillButtonGroup);
            app.schwerButton.Text = 'schwer';
            app.KeyboardControlLabel = uilabel(app.MenuPanel);
            app.KeyboardControlLabel.Text = 'Keyboard Control:';
            app.Player1ArrowUpArrowDownLabel = uilabel(app.MenuPanel);
            app.Player1ArrowUpArrowDownLabel.Text = 'Player1: Arrow Up / Arrow Down';
            app.Player2WSLabel = uilabel(app.MenuPanel);
            app.Player2WSLabel.Text = 'Player2: W / S';
            app.PONGLabel = uilabel(app.MenuPanel);
            app.PONGLabel.Text = 'PONG';
            app.GameModeButtonGroup = uibuttongroup(app.MenuPanel);
            app.GameModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GameModeButtonGroupSelectionChanged, true);
            app.GameModeButtonGroup.Title = 'Mode';
            app.onePlayer = uiradiobutton(app.GameModeButtonGroup);
            app.onePlayer.Text = '1 Player';
            app.onePlayer.Value = true;
            app.twoPlayer = uiradiobutton(app.GameModeButtonGroup);
            app.twoPlayer.Text = '2 Players';
            app.RoundsSpinnerLabel = uilabel(app.MenuPanel);
            app.RoundsSpinnerLabel.Text = 'Rounds';
            app.RoundsSpinner = uispinner(app.MenuPanel);
            app.RoundsSpinner.Limits = [1 50];
            app.RoundsSpinner.ValueDisplayFormat = '%.0f';
            app.RoundsSpinner.Value = 5;
            app.PausePLabel = uilabel(app.MenuPanel);
            app.PausePLabel.Text = 'Pause: P';
            app.ExitQLabel = uilabel(app.MenuPanel);
            app.ExitQLabel.Text = 'Exit: Q';
            app.Version10Label = uilabel(app.MenuPanel);
            app.Version10Label.Text = 'Version 1.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pong

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pong)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pong)
        end
    end
end